classdef pokeri_kello < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        BigBlindGauge                matlab.ui.control.LinearGauge
        BigBlindGaugeLabel           matlab.ui.control.Label
        TimerGauge                   matlab.ui.control.SemicircularGauge
        TimerGaugeLabel              matlab.ui.control.Label
        EditField                    matlab.ui.control.EditField
        EditFieldLabel               matlab.ui.control.Label
        LeveltimeEditField           matlab.ui.control.NumericEditField
        LeveltimeEditFieldLabel      matlab.ui.control.Label
        BBEditField                  matlab.ui.control.NumericEditField
        BBEditFieldLabel             matlab.ui.control.Label
        StartingstackEditField       matlab.ui.control.NumericEditField
        StartingstackEditFieldLabel  matlab.ui.control.Label
        Switch                       matlab.ui.control.ToggleSwitch
        SwitchLabel                  matlab.ui.control.Label
        Button                       matlab.ui.control.Button
    end

    
    properties (Access = public)
        
        StartingStack = 5000;
        BB = 100;
        Level_time = 20;
        clock_set = 0;
        i = 1;
        
        % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            if app.Switch.Value == "Off"
                app.EditField.Value = 'pois';
            else
                app.EditField.Value = 'päällä';
                while app.Switch.Value == "On"
                    for val = 1:app.LeveltimeEditField.Value
                        while app.Switch.Value == "On"
                            app.TimerGauge.Value = app.LeveltimeEditField.Value-app.i+1;
                            app.i = app.i+1;
                            
                            if app.TimerGauge.Value == 0
                                sound(1);
                                app.i = 1;
                                app.BigBlindGauge.Value = app.BigBlindGauge.Value + 50;
                                pause(1);
                                break
                            end
                            pause(60);
                        end
                    end
                    if app.TimerGauge.Value == 0
                        sound(1);
                        app.i = 1;
                        
                    end
                end
            end


        end

        % Value changed function: BBEditField
        function BBEditFieldValueChanged(app, event)
            app.BB = app.BBEditField.Value;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
           app.TimerGauge.Limits = [0,app.LeveltimeEditField.Value];
           app.BigBlindGauge.Value = app.BBEditField.Value;
           app.TimerGauge.Value = app.LeveltimeEditField.Value;
           app.i = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.StartingstackEditFieldLabel = uilabel(app.UIFigure);
            app.StartingstackEditFieldLabel.Text = 'Starting stack';
            app.StartingstackEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartingstackEditField.Value = 5000;
            app.BBEditFieldLabel = uilabel(app.UIFigure);
            app.BBEditFieldLabel.Text = {'BB'; ''};
            app.BBEditField = uieditfield(app.UIFigure, 'numeric');
            app.BBEditField.ValueChangedFcn = createCallbackFcn(app, @BBEditFieldValueChanged, true);
            app.BBEditField.Value = 50;
            app.LeveltimeEditFieldLabel = uilabel(app.UIFigure);
            app.LeveltimeEditFieldLabel.Text = {'Level time'; ''};
            app.LeveltimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.LeveltimeEditField.Value = 20;
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.TimerGaugeLabel = uilabel(app.UIFigure);
            app.TimerGaugeLabel.Text = 'Timer';
            app.TimerGauge = uigauge(app.UIFigure, 'semicircular');
            app.TimerGauge.Limits = [0 20];
            app.BigBlindGaugeLabel = uilabel(app.UIFigure);
            app.BigBlindGaugeLabel.Text = 'Big Blind';
            app.BigBlindGauge = uigauge(app.UIFigure, 'linear');
            app.BigBlindGauge.Limits = [0 500];
            app.BigBlindGauge.MajorTicks = [0 50 100 150 200 250 300 350 400 450 500 550 600];
            app.BigBlindGauge.MajorTickLabels = {'0', '50', '100', '150', '200', '300', '400', '500', '600', '800', '1000', '1200', '1600'};
            app.BigBlindGauge.MinorTicks = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pokeri_kello

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end